classdef path_planning_gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PathPlanningUIFigure            matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        TrailLimitEditField             matlab.ui.control.NumericEditField
        TrailLimitEditFieldLabel        matlab.ui.control.Label
        V2EditField                     matlab.ui.control.NumericEditField
        V2EditFieldLabel                matlab.ui.control.Label
        V1EditField                     matlab.ui.control.NumericEditField
        V1EditFieldLabel                matlab.ui.control.Label
        z_maxEditFieldLabel_3           matlab.ui.control.Label
        z_maxEditFieldLabel_2           matlab.ui.control.Label
        MethodSwitch                    matlab.ui.control.ToggleSwitch
        MethodSwitchLabel               matlab.ui.control.Label
        IterationsEditField             matlab.ui.control.NumericEditField
        IterationsEditFieldLabel        matlab.ui.control.Label
        z_maxEditField                  matlab.ui.control.NumericEditField
        z_maxEditFieldLabel             matlab.ui.control.Label
        SwarmSizeEditField              matlab.ui.control.NumericEditField
        SwarmSizeEditFieldLabel         matlab.ui.control.Label
        y_maxEditField                  matlab.ui.control.NumericEditField
        y_maxEditFieldLabel             matlab.ui.control.Label
        x_maxEditField                  matlab.ui.control.NumericEditField
        x_maxEditFieldLabel             matlab.ui.control.Label
        dimensionEditField              matlab.ui.control.NumericEditField
        dimensionEditFieldLabel         matlab.ui.control.Label
        deltaEditField                  matlab.ui.control.NumericEditField
        deltaEditFieldLabel             matlab.ui.control.Label
        d_safeEditField                 matlab.ui.control.NumericEditField
        d_safeEditFieldLabel            matlab.ui.control.Label
        alphaEditField                  matlab.ui.control.NumericEditField
        alphaEditFieldLabel             matlab.ui.control.Label
        c_2EditField                    matlab.ui.control.NumericEditField
        c_2EditFieldLabel               matlab.ui.control.Label
        c_1EditField                    matlab.ui.control.NumericEditField
        c_1EditFieldLabel               matlab.ui.control.Label
        c_minEditField                  matlab.ui.control.NumericEditField
        c_minEditFieldLabel             matlab.ui.control.Label
        c_maxEditField                  matlab.ui.control.NumericEditField
        c_maxEditFieldLabel             matlab.ui.control.Label
        UITable_2                       matlab.ui.control.Table
        UITable                         matlab.ui.control.Table
        SolveButton                     matlab.ui.control.Button
        w_minEditField                  matlab.ui.control.NumericEditField
        w_minEditFieldLabel             matlab.ui.control.Label
        w_maxEditField                  matlab.ui.control.NumericEditField
        w_maxEditFieldLabel             matlab.ui.control.Label
        NumberofDronesEditField         matlab.ui.control.NumericEditField
        NumberofDronesEditFieldLabel    matlab.ui.control.Label
        RightPanel                      matlab.ui.container.Panel
        STATUSEditField_3               matlab.ui.control.EditField
        STATUSEditField_3Label          matlab.ui.control.Label
        NumberofFailuresEditField       matlab.ui.control.NumericEditField
        NumberofFailuresEditFieldLabel  matlab.ui.control.Label
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
        gridPlot                        matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        wmax % Description
        wmin % Description
        cmax % Description
        cmin % Description
        alpha % Description
        d_safe % Description
        delta % Description
        dimension % Description
        max_iter % Description
        swarm_size % Description
        xmax % Description
        ymax % Description
        zmax % Description
        initial_pos_drones % Description
        goal_pos_drones % Description
        num_drones % Description
        V1 % Description
        V2 % Description
        initial_pos_drones_transposed % Description
        goal_pos_drones_transposed
        trail_limit % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.PathPlanningUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {939, 939};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {453, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Button pushed function: SolveButton
        function SolveButtonPushed(app, event)

            app.STATUSEditField_3.Value = 'Solving';
            if convertCharsToStrings(app.MethodSwitch.Value) == 'CIPSO'           
                particles = cell(app.swarm_size, app.num_drones);
                mu = 4;
                mountains = [4 5; 7 8; 6 15; 4 4; 4 4]; %% h; xm; ym; xt; yt
                radars = [12; 10; 0; 3.7]; %x,y,z,R
                num_segments = 20;
                app.initial_pos_drones_transposed = transpose(app.initial_pos_drones);
                app.goal_pos_drones_transposed = transpose(app.goal_pos_drones);
                fitness_history = zeros(app.max_iter, app.num_drones);

                for i=1:app.num_drones
                    particles(:,i) = logistic_map(app.initial_pos_drones_transposed(:,i), app.goal_pos_drones_transposed(:,i), app.swarm_size, app.dimension, mu, app.xmax, app.ymax, app.zmax);
                end
            
                for j=1:app.num_drones
                    for i=1:app.swarm_size
                        cost(i,j) = mountain_terrain_cost(cell2mat(particles(i,j)), mountains, num_segments);
                        cost(i,j) = cost(i,j) + path_length_cost(cell2mat(particles(i,j)));
                        cost(i,j) = cost(i,j) + radar_threat_cost(cell2mat(particles(i,j)), radars(1), radars(2), radars(3), radars(4), num_segments, app.delta);
                    end
                    pbest_cost(:,j) = cost(:,j);
                    pbest_path(:,j) = particles(:,j);
                    [min_cost, min_index] = min(cost(:,j)); 
                    gbest_cost(j) = 999;
                    gbest_path(j) = particles(min_index, j);
                end
            
                velocities = cell(size(particles));
                velocities(:,:) = mat2cell(zeros(3,app.dimension+1),3 ,app.dimension+1);
                line_list = [];
                
                [X,Y] = meshgrid(0:0.1:app.xmax, 0:app.ymax);
                mountain_size = size(X);
                for i = 1:mountain_size(1)
                    for j = 1:mountain_size(2)
                        Z1(i,j) = mountain_model(mountains(1,1), mountains(2,1), mountains(3,1), mountains(4,1), mountains(5,1), X(i,j), Y(i,j));
                        Z2(i,j) = mountain_model(mountains(1,2), mountains(2,2), mountains(3,2), mountains(4,2), mountains(5,2), X(i,j), Y(i,j));
                    end
                end
                surf(app.gridPlot,X,Y,Z1)
                hold(app.gridPlot, 'on');
                surf(app.gridPlot,X,Y,Z2)
                [Xs, Ys, Zs] = sphere;
                r = radars(4);
                X2 = Xs * r;
                Y2 = Ys * r;
                Z2 = Zs * r;
                surf(app.gridPlot, X2+radars(1),Y2+radars(2),Z2+radars(3))
                
                hold(app.gridPlot, 'off');
                drawnow;
                
                %% Main Loop
            
                for i=1:app.num_drones
                    for j = 1:app.max_iter
                        w = app.wmax - (app.wmax-app.wmin)*j/app.max_iter;
                        c1 = app.cmax - (app.cmax - app.cmin)*j/app.max_iter;
                        c2 = app.cmin - (app.cmax - app.cmin)*j/app.max_iter;
                        vmax = app.V1 - (app.V1 - app.V2)*j/app.max_iter;
                        [particles(:,i), velocities(:,i)] = generate_new_particles(particles(:,i), velocities(:,i), pbest_path(:,i), gbest_path(i),w, c1, c2, vmax);
                        particles(:,i) = saturate_on_boundaries(particles(:,i), app.xmax, app.ymax, app.zmax);
                        cost(:,i) = calculate_fitness(particles(:,i), mountains, gbest_path(1:i-1), num_segments, app.d_safe, radars, app.delta);
                        for k = 1:app.swarm_size
                            if cost(k,i) < pbest_cost(k,i)
                                pbest_cost(k,i) = cost(k,i);
                                pbest_path(k,i) = particles(k,i);
                            end
                            if cost(k,i) < gbest_cost(i)
                                gbest_cost(i) = cost(k,i);
                                gbest_path(i) = particles(k,i);
                                line_list_size = size(line_list);
                                for p = 1:line_list_size(2)
                                    try
                                        delete(line_list(p));
                                    catch ME
                                        
                                    end
                                    
                                end
                                for p = 1:i
                                    path = cell2mat(gbest_path(p));
                                    line_list(i) = line(app.gridPlot,path(1,:),path(2,:),path(3,:), 'Color', rand(1,3), 'LineStyle', '-', 'LineWidth', 2.5);
                                end
                                drawnow;
                            end
                        end
                        [particles(:,i), velocities(:,i), pbest_cost(:,i), pbest_path(:,i), cost(:,i)] = sort_particles(particles(:,i), velocities(:,i), cost(:,i), pbest_cost(:,i), pbest_path(:,i));
                        [particles(:,i), velocities(:,i)] = execute_mutation(particles(:,i), velocities(:,i), app.alpha);
                        fitness_history(j,i) = gbest_cost(i);
                    end
                end
            elseif convertCharsToStrings(app.MethodSwitch.Value) == 'IPSO'
                particles = cell(app.swarm_size, app.num_drones);
                all_particles = cell(app.swarm_size * 100, app.num_drones);
                mu = 4;
                mountains = [4 5; 7 8; 6 15; 4 4; 4 4]; %% h; xm; ym; xt; yt
                radars = [12; 10; 0; 3.7]; %x,y,z,R
                num_segments = 20;
                app.initial_pos_drones_transposed = transpose(app.initial_pos_drones);
                app.goal_pos_drones_transposed = transpose(app.goal_pos_drones);
            
                for i=1:app.num_drones
                    all_particles(:,i) = logistic_map(app.initial_pos_drones_transposed(:,i), app.goal_pos_drones_transposed(:,i), app.swarm_size * 100, app.dimension, mu, app.xmax, app.ymax, app.zmax);
                end

                particles = all_particles(1:app.swarm_size,:);
                trails = zeros(app.swarm_size, app.num_drones);
                last_index_taken = app.swarm_size + 1;
                            
                for j=1:app.num_drones
                    for i=1:app.swarm_size
                        cost(i,j) = mountain_terrain_cost(cell2mat(particles(i,j)), mountains, num_segments);
                        cost(i,j) = cost(i,j) + path_length_cost(cell2mat(particles(i,j)));
                        cost(i,j) = cost(i,j) + radar_threat_cost(cell2mat(particles(i,j)), radars(1), radars(2), radars(3), radars(4), num_segments, app.delta);
                    end
                    pbest_cost(:,j) = cost(:,j);
                    pbest_path(:,j) = particles(:,j);
                    [min_cost, min_index] = min(cost(:,j)); 
                    gbest_cost(j) = 999;
                    gbest_path(j) = particles(min_index, j);
                end
            
                velocities = cell(size(particles));
                velocities(:,:) = mat2cell(zeros(3,app.dimension+1),3 ,app.dimension+1);
                line_list = [];
                
                [X,Y] = meshgrid(0:0.1:app.xmax, 0:app.ymax);
                mountain_size = size(X);
                for i = 1:mountain_size(1)
                    for j = 1:mountain_size(2)
                        Z1(i,j) = mountain_model(mountains(1,1), mountains(2,1), mountains(3,1), mountains(4,1), mountains(5,1), X(i,j), Y(i,j));
                        Z2(i,j) = mountain_model(mountains(1,2), mountains(2,2), mountains(3,2), mountains(4,2), mountains(5,2), X(i,j), Y(i,j));
                    end
                end
                surf(app.gridPlot,X,Y,Z1)
                hold(app.gridPlot, 'on');
                surf(app.gridPlot,X,Y,Z2)
                [Xs, Ys, Zs] = sphere;
                r = radars(4);
                X2 = Xs * r;
                Y2 = Ys * r;
                Z2 = Zs * r;
                surf(app.gridPlot, X2+radars(1),Y2+radars(2),Z2+radars(3))
                
                hold(app.gridPlot, 'off');
                drawnow;
                
                %% Main Loop
            
                for i=1:app.num_drones
                    for j = 1:app.max_iter
                        w = app.wmax - (app.wmax-app.wmin)*j/app.max_iter;
                        c1 = app.cmax - (app.cmax - app.cmin)*j/app.max_iter;
                        c2 = app.cmin - (app.cmax - app.cmin)*j/app.max_iter;
                        vmax = app.V1 - (app.V1 - app.V2)*j/app.max_iter;
                        [particles(:,i), velocities(:,i)] = generate_new_particles(particles(:,i), velocities(:,i), pbest_path(:,i), gbest_path(i),w, c1, c2, vmax);
                        particles(:,i) = saturate_on_boundaries(particles(:,i), app.xmax, app.ymax, app.zmax);
                        cost(:,i) = calculate_fitness(particles(:,i), mountains, gbest_path(1:i-1), num_segments, app.d_safe, radars, app.delta);
                        for k = 1:app.swarm_size
                            if cost(k,i) < pbest_cost(k,i)
                                pbest_cost(k,i) = cost(k,i);
                                pbest_path(k,i) = particles(k,i);
                                trails(k,i) = 0;
                            else
                                trails(k,i) = trails(k,i) + 1;
                            end
                            if cost(k,i) < gbest_cost(i)
                                gbest_cost(i) = cost(k,i);
                                gbest_path(i) = particles(k,i);
                                line_list_size = size(line_list);
                                for p = 1:line_list_size(2)
                                    try
                                        delete(line_list(p));
                                    catch ME
                                        
                                    end
                                    
                                end
                                for p = 1:i
                                    path = cell2mat(gbest_path(p));
                                    line_list(i) = line(app.gridPlot,path(1,:),path(2,:),path(3,:), 'Color', rand(1,3), 'LineStyle', '-', 'LineWidth', 2.5);
                                end
                                drawnow;
                            end
                            if (trails(k,i) > app.trail_limit)
                                particles(k,i) = all_particles(last_index_taken,i);
                                trails(k,i) = 0;
                                last_index_taken = last_index_taken + 1;
                            end
                        end
                        fitness_history(j,i) = gbest_cost(i);
                    end
                end
            end
            app.STATUSEditField_3.Value = 'Finished';
            failure = 0;
            for i = 1:app.num_drones
                if gbest_cost(i) > 2
                    failure = failure + 1;
                end
            end
            app.NumberofFailuresEditField.Value = failure;
            plot(app.UIAxes2, 1:app.max_iter, fitness_history(:,1));
            hold(app.UIAxes2, 'on');
            for i = 2:app.num_drones
                plot(app.UIAxes2, 1:app.max_iter, fitness_history(:,i));
            end
            hold(app.UIAxes2, 'off');
            path = cell2mat(gbest_path(1));
            plot(app.UIAxes, path(1,:), path(2,:));
            hold(app.UIAxes, 'on');
            for i = 2:app.num_drones
                path = cell2mat(gbest_path(i));
                plot(app.UIAxes, path(1,:), path(2,:));
            end
            %%post process
        end

        % Value changed function: w_maxEditField
        function w_maxEditFieldValueChanged(app, event)
            value = app.w_maxEditField.Value;
            app.wmax = value;
        end

        % Value changed function: c_maxEditField
        function c_maxEditFieldValueChanged(app, event)
            value = app.c_maxEditField.Value;
            app.cmax = value;
        end

        % Value changed function: c_minEditField
        function c_minEditFieldValueChanged(app, event)
            value = app.c_minEditField.Value;
            app.cmin = value;
        end

        % Value changed function: w_minEditField
        function w_minEditFieldValueChanged(app, event)
            value = app.w_minEditField.Value;
            app.wmin = value;
        end

        % Value changed function: c_1EditField
        function c_1EditFieldValueChanged(app, event)
            value = app.c_1EditField.Value;
            app.c1 = value;
        end

        % Value changed function: c_2EditField
        function c_2EditFieldValueChanged(app, event)
            value = app.c_2EditField.Value;
            app.c2 = value;
        end

        % Value changed function: alphaEditField
        function alphaEditFieldValueChanged(app, event)
            value = app.alphaEditField.Value;
            app.alpha = value;
        end

        % Value changed function: d_safeEditField
        function d_safeEditFieldValueChanged(app, event)
            value = app.d_safeEditField.Value;
            app.d_safe = value;
        end

        % Value changed function: deltaEditField
        function deltaEditFieldValueChanged(app, event)
            value = app.deltaEditField.Value;
            app.delta = value;
        end

        % Value changed function: dimensionEditField
        function dimensionEditFieldValueChanged(app, event)
            value = app.dimensionEditField.Value;
            app.dimension = value;
        end

        % Value changed function: x_maxEditField
        function x_maxEditFieldValueChanged(app, event)
            value = app.x_maxEditField.Value;
            app.xmax = value;
            xlim(app.gridPlot,[0 app.xmax]);
        end

        % Value changed function: y_maxEditField
        function y_maxEditFieldValueChanged(app, event)
            value = app.y_maxEditField.Value;
            app.ymax = value;
            ylim(app.gridPlot,[0 app.ymax]);
        end

        % Value changed function: z_maxEditField
        function z_maxEditFieldValueChanged(app, event)
            value = app.z_maxEditField.Value;
            app.zmax = value;
            zlim(app.gridPlot,[0 app.zmax]);
        end

        % Value changed function: IterationsEditField
        function IterationsEditFieldValueChanged(app, event)
            value = app.IterationsEditField.Value;
            app.max_iter = value;
        end

        % Value changed function: SwarmSizeEditField
        function SwarmSizeEditFieldValueChanged(app, event)
            value = app.SwarmSizeEditField.Value;
            app.swarm_size = value;
        end

        % Value changed function: NumberofDronesEditField
        function NumberofDronesEditFieldValueChanged(app, event)
            value = app.NumberofDronesEditField.Value;
            app.num_drones = value;
            drone_number = (1:app.num_drones).';
            x_coordinate = zeros(app.num_drones,1);
            y_coordinate = zeros(app.num_drones,1);
            z_coordinate = zeros(app.num_drones,1);
            T = table(drone_number, x_coordinate, y_coordinate, z_coordinate);
            app.UITable.Data = T;
            app.UITable.ColumnEditable = [false, true, true, true];
            app.UITable_2.Data = T;
            app.UITable_2.ColumnEditable = [false, true, true, true];
            app.initial_pos_drones = zeros([app.num_drones, 3]);
            app.goal_pos_drones = zeros([app.num_drones, 3]);
        end

        % Cell edit callback: UITable_2
        function UITable_2CellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            app.initial_pos_drones(indices(1), indices(2)-1) = newData;
            
        end

        % Value changed function: V1EditField
        function V1EditFieldValueChanged(app, event)
            value = app.V1EditField.Value;
            app.V1 = value;
        end

        % Value changed function: V2EditField
        function V2EditFieldValueChanged(app, event)
            value = app.V2EditField.Value;
            app.V2 = value;
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            app.goal_pos_drones(indices(1), indices(2)-1) = newData;
        end

        % Value changed function: TrailLimitEditField
        function TrailLimitEditFieldValueChanged(app, event)
            value = app.TrailLimitEditField.Value;
            app.trail_limit = value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PathPlanningUIFigure = uifigure('Visible', 'off');
            app.PathPlanningUIFigure.Name = 'Path Planning GUI';
            app.PathPlanningUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.PathPlanningUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.NumberofDronesEditFieldLabel = uilabel(app.LeftPanel);
            app.NumberofDronesEditFieldLabel.Text = 'Number of Drones';
            app.NumberofDronesEditField = uieditfield(app.LeftPanel, 'numeric');
            app.NumberofDronesEditField.Limits = [0 10000000];
            app.NumberofDronesEditField.ValueDisplayFormat = '%8.f';
            app.NumberofDronesEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofDronesEditFieldValueChanged, true);
            app.w_maxEditFieldLabel = uilabel(app.LeftPanel);
            app.w_maxEditFieldLabel.Text = 'w_max';
            app.w_maxEditField = uieditfield(app.LeftPanel, 'numeric');
            app.w_maxEditField.Limits = [0 10000000];
            app.w_maxEditField.ValueDisplayFormat = '%.3f';
            app.w_maxEditField.ValueChangedFcn = createCallbackFcn(app, @w_maxEditFieldValueChanged, true);
            app.w_minEditFieldLabel = uilabel(app.LeftPanel);
            app.w_minEditFieldLabel.Text = 'w_min';
            app.w_minEditField = uieditfield(app.LeftPanel, 'numeric');
            app.w_minEditField.Limits = [0 10000000];
            app.w_minEditField.ValueDisplayFormat = '%.3f';
            app.w_minEditField.ValueChangedFcn = createCallbackFcn(app, @w_minEditFieldValueChanged, true);
            app.SolveButton = uibutton(app.LeftPanel, 'push');
            app.SolveButton.ButtonPushedFcn = createCallbackFcn(app, @SolveButtonPushed, true);
            app.SolveButton.Text = 'Solve';
            app.UITable = uitable(app.LeftPanel);
            app.UITable.ColumnName = {'Drone No'; 'X Position'; 'Y Position'; 'Z Position'};
            app.UITable.RowName = {};
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.UITable_2 = uitable(app.LeftPanel);
            app.UITable_2.ColumnName = {'Drone No'; 'X Position'; 'Y Position'; 'Z Position'};
            app.UITable_2.RowName = {};
            app.UITable_2.CellEditCallback = createCallbackFcn(app, @UITable_2CellEdit, true);
            app.c_maxEditFieldLabel = uilabel(app.LeftPanel);
            app.c_maxEditFieldLabel.Text = 'c_max';
            app.c_maxEditField = uieditfield(app.LeftPanel, 'numeric');
            app.c_maxEditField.Limits = [0 10000000];
            app.c_maxEditField.ValueDisplayFormat = '%.3f';
            app.c_maxEditField.ValueChangedFcn = createCallbackFcn(app, @c_maxEditFieldValueChanged, true);
            app.c_minEditFieldLabel = uilabel(app.LeftPanel);
            app.c_minEditFieldLabel.Text = 'c_min';
            app.c_minEditField = uieditfield(app.LeftPanel, 'numeric');
            app.c_minEditField.Limits = [0 10000000];
            app.c_minEditField.ValueDisplayFormat = '%.3f';
            app.c_minEditField.ValueChangedFcn = createCallbackFcn(app, @c_minEditFieldValueChanged, true);
            app.c_1EditFieldLabel = uilabel(app.LeftPanel);
            app.c_1EditFieldLabel.Text = 'c_1';
            app.c_1EditField = uieditfield(app.LeftPanel, 'numeric');
            app.c_1EditField.Limits = [0 10000000];
            app.c_1EditField.ValueDisplayFormat = '%.3f';
            app.c_1EditField.ValueChangedFcn = createCallbackFcn(app, @c_1EditFieldValueChanged, true);
            app.c_2EditFieldLabel = uilabel(app.LeftPanel);
            app.c_2EditFieldLabel.Text = 'c_2';
            app.c_2EditField = uieditfield(app.LeftPanel, 'numeric');
            app.c_2EditField.Limits = [0 10000000];
            app.c_2EditField.ValueDisplayFormat = '%.3f';
            app.c_2EditField.ValueChangedFcn = createCallbackFcn(app, @c_2EditFieldValueChanged, true);
            app.alphaEditFieldLabel = uilabel(app.LeftPanel);
            app.alphaEditFieldLabel.Text = 'alpha';
            app.alphaEditField = uieditfield(app.LeftPanel, 'numeric');
            app.alphaEditField.Limits = [0 10000000];
            app.alphaEditField.ValueDisplayFormat = '%.3f';
            app.alphaEditField.ValueChangedFcn = createCallbackFcn(app, @alphaEditFieldValueChanged, true);
            app.d_safeEditFieldLabel = uilabel(app.LeftPanel);
            app.d_safeEditFieldLabel.Text = 'd_safe';
            app.d_safeEditField = uieditfield(app.LeftPanel, 'numeric');
            app.d_safeEditField.Limits = [0 10000000];
            app.d_safeEditField.ValueDisplayFormat = '%.3f';
            app.d_safeEditField.ValueChangedFcn = createCallbackFcn(app, @d_safeEditFieldValueChanged, true);
            app.deltaEditFieldLabel = uilabel(app.LeftPanel);
            app.deltaEditFieldLabel.Text = 'delta';
            app.deltaEditField = uieditfield(app.LeftPanel, 'numeric');
            app.deltaEditField.Limits = [0 10000000];
            app.deltaEditField.ValueDisplayFormat = '%.3f';
            app.deltaEditField.ValueChangedFcn = createCallbackFcn(app, @deltaEditFieldValueChanged, true);
            app.dimensionEditFieldLabel = uilabel(app.LeftPanel);
            app.dimensionEditFieldLabel.Text = 'dimension';
            app.dimensionEditField = uieditfield(app.LeftPanel, 'numeric');
            app.dimensionEditField.Limits = [0 10000000];
            app.dimensionEditField.ValueDisplayFormat = '%.0f';
            app.dimensionEditField.ValueChangedFcn = createCallbackFcn(app, @dimensionEditFieldValueChanged, true);
            app.x_maxEditFieldLabel = uilabel(app.LeftPanel);
            app.x_maxEditFieldLabel.Text = 'x_max';
            app.x_maxEditField = uieditfield(app.LeftPanel, 'numeric');
            app.x_maxEditField.Limits = [0 10000000];
            app.x_maxEditField.ValueDisplayFormat = '%.0f';
            app.x_maxEditField.ValueChangedFcn = createCallbackFcn(app, @x_maxEditFieldValueChanged, true);
            app.y_maxEditFieldLabel = uilabel(app.LeftPanel);
            app.y_maxEditFieldLabel.Text = 'y_max';
            app.y_maxEditField = uieditfield(app.LeftPanel, 'numeric');
            app.y_maxEditField.Limits = [0 10000000];
            app.y_maxEditField.ValueDisplayFormat = '%.0f';
            app.y_maxEditField.ValueChangedFcn = createCallbackFcn(app, @y_maxEditFieldValueChanged, true);
            app.SwarmSizeEditFieldLabel = uilabel(app.LeftPanel);
            app.SwarmSizeEditFieldLabel.Text = 'Swarm Size';
            app.SwarmSizeEditField = uieditfield(app.LeftPanel, 'numeric');
            app.SwarmSizeEditField.Limits = [0 10000000];
            app.SwarmSizeEditField.ValueDisplayFormat = '%8.f';
            app.SwarmSizeEditField.ValueChangedFcn = createCallbackFcn(app, @SwarmSizeEditFieldValueChanged, true);
            app.z_maxEditFieldLabel = uilabel(app.LeftPanel);
            app.z_maxEditFieldLabel.Text = 'z_max';
            app.z_maxEditField = uieditfield(app.LeftPanel, 'numeric');
            app.z_maxEditField.Limits = [0 10000000];
            app.z_maxEditField.ValueDisplayFormat = '%.0f';
            app.z_maxEditField.ValueChangedFcn = createCallbackFcn(app, @z_maxEditFieldValueChanged, true);
            app.IterationsEditFieldLabel = uilabel(app.LeftPanel);
            app.IterationsEditFieldLabel.Text = 'Iterations';
            app.IterationsEditField = uieditfield(app.LeftPanel, 'numeric');
            app.IterationsEditField.Limits = [0 10000000];
            app.IterationsEditField.ValueDisplayFormat = '%.0f';
            app.IterationsEditField.ValueChangedFcn = createCallbackFcn(app, @IterationsEditFieldValueChanged, true);
            app.MethodSwitchLabel = uilabel(app.LeftPanel);
            app.MethodSwitchLabel.Text = 'Method';
            app.MethodSwitch = uiswitch(app.LeftPanel, 'toggle');
            app.MethodSwitch.Items = {'CIPSO', 'IPSO'};
            app.MethodSwitch.Value = 'CIPSO';
            app.z_maxEditFieldLabel_2 = uilabel(app.LeftPanel);
            app.z_maxEditFieldLabel_2.Text = 'Initial Positions';
            app.z_maxEditFieldLabel_3 = uilabel(app.LeftPanel);
            app.z_maxEditFieldLabel_3.Text = 'Goal Positions';
            app.V1EditFieldLabel = uilabel(app.LeftPanel);
            app.V1EditFieldLabel.Text = 'V1';
            app.V1EditField = uieditfield(app.LeftPanel, 'numeric');
            app.V1EditField.Limits = [0 10000000];
            app.V1EditField.ValueDisplayFormat = '%.3f';
            app.V1EditField.ValueChangedFcn = createCallbackFcn(app, @V1EditFieldValueChanged, true);
            app.V2EditFieldLabel = uilabel(app.LeftPanel);
            app.V2EditFieldLabel.Text = 'V2';
            app.V2EditField = uieditfield(app.LeftPanel, 'numeric');
            app.V2EditField.Limits = [0 10000000];
            app.V2EditField.ValueDisplayFormat = '%.3f';
            app.V2EditField.ValueChangedFcn = createCallbackFcn(app, @V2EditFieldValueChanged, true);
            app.TrailLimitEditFieldLabel = uilabel(app.LeftPanel);
            app.TrailLimitEditFieldLabel.Text = 'Trail Limit';
            app.TrailLimitEditField = uieditfield(app.LeftPanel, 'numeric');
            app.TrailLimitEditField.Limits = [0 10000000];
            app.TrailLimitEditField.ValueDisplayFormat = '%8.f';
            app.TrailLimitEditField.ValueChangedFcn = createCallbackFcn(app, @TrailLimitEditFieldValueChanged, true);
            app.RightPanel = uipanel(app.GridLayout);
            app.gridPlot = uiaxes(app.RightPanel);
            title(app.gridPlot, 'Grid')
            xlabel(app.gridPlot, 'X Position')
            ylabel(app.gridPlot, 'Y Position')
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Paths')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.RightPanel);
            title(app.UIAxes2, 'Fitness vs Iteration')
            xlabel(app.UIAxes2, 'Iteration')
            ylabel(app.UIAxes2, 'Fitness Value')
            zlabel(app.UIAxes2, 'Z')
            app.NumberofFailuresEditFieldLabel = uilabel(app.RightPanel);
            app.NumberofFailuresEditFieldLabel.Text = 'Number of Failures';
            app.NumberofFailuresEditField = uieditfield(app.RightPanel, 'numeric');
            app.NumberofFailuresEditField.Limits = [0 10000000];
            app.NumberofFailuresEditField.ValueDisplayFormat = '%.0f';
            app.NumberofFailuresEditField.Editable = 'off';
            app.STATUSEditField_3Label = uilabel(app.RightPanel);
            app.STATUSEditField_3Label.Text = 'STATUS';
            app.STATUSEditField_3 = uieditfield(app.RightPanel, 'text');
            app.STATUSEditField_3.Editable = 'off';
            app.STATUSEditField_3.Value = 'Waiting';
            % Show the figure after all components are created
            app.PathPlanningUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = path_planning_gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PathPlanningUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PathPlanningUIFigure)
        end
    end
end